classdef ParkingMeterGUI < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    ParkingMeterUIFigure  matlab.ui.Figure
    PanelTicketLabel      matlab.ui.container.Panel
    GridTicket            matlab.ui.container.GridLayout
    ImageTicket           matlab.ui.control.Image
    LabelTag              matlab.ui.container.GridLayout
    GridLayout            matlab.ui.container.GridLayout
    Panel7Segments        matlab.ui.container.Panel
    Grid7Segments         matlab.ui.container.GridLayout
    MasterSwitchLabel     matlab.ui.control.Label
    SwitchMaster          matlab.ui.control.Switch
    LedGiveChange         matlab.ui.control.Lamp
    GivechangeLampLabel   matlab.ui.control.Label
    LedAddCoins           matlab.ui.control.Lamp
    AddcoinsLampLabel     matlab.ui.control.Label
    CoinsforchangeLabel   matlab.ui.control.Label
    ImageFlap             matlab.ui.control.Image
    ButtonRed             matlab.ui.control.Button
    ButtonGreen           matlab.ui.control.Button
    ImageCoin_010         matlab.ui.control.Image
    ImageCoin_020         matlab.ui.control.Image
    ImageCoin_050         matlab.ui.control.Image
    ImageCoin_100         matlab.ui.control.Image
    ImageCoin_200         matlab.ui.control.Image
    ImageCashBoxKey       matlab.ui.control.Image
    ImageCoinSlot_1       matlab.ui.control.Image
    ImageCoinSlot_2       matlab.ui.control.Image
    ImageCoinSlot_3       matlab.ui.control.Image
    ImageCoinSlot_4       matlab.ui.control.Image
    ImageCoinSlot_5       matlab.ui.control.Image
    ImageCoinSlot_6       matlab.ui.control.Image
    ImageCoinSlot_7       matlab.ui.control.Image
    ImageCoinSlot_8       matlab.ui.control.Image
    ImageCoinSlot_9       matlab.ui.control.Image
    ImageCoinsChange      matlab.ui.control.Image
    ImageInstructions     matlab.ui.control.Image
    ImageBackground       matlab.ui.control.Image
  end

  properties (Constant = true)
    % Bit mask encoding of coins
    BIT_MASK_010_CENTS = 0x01;
    BIT_MASK_020_CENTS = 0x02;
    BIT_MASK_050_CENTS = 0x04;
    BIT_MASK_100_CENTS = 0x08;
    BIT_MASK_200_CENTS = 0x10;
    BIT_MASK_CHANGE_COINS = 0x0F;
    % Bit mask encoding of buttons
    BIT_MASK_GREEN_BUTTON  = 0x01;
    BIT_MASK_RED_BUTTON    = 0x02;
    BIT_MASK_MASTER_SWITCH = 0x04;
    % Bit mask encoding of the ticket
    BIT_MASK_PRINT_TICKET = 0x10;
    % Bit mask encoding of LEDs
    BIT_MASK_RETURN_LED = 0x20;
    BIT_MASK_ADD_LED    = 0x40;
    % Bit mask encoding of the coin's flap
    BIT_MASK_COINS_FLAP = 0x80;
    % 7-segment displays parameters
    NB_DISPLAYS      = 3;
    BACKGROUND_COLOR = [127 127 0]/255; % RGB
    SEGMENTS_COLOR   = [131 131 0]/255; % RGB
    % Internal App timer duration
    TIMER_PERIOD = 0.01;
    % Time delay when interacting with the GUI
    TIME_COINS_INSERTED = 0.20;
    TIME_TICKET_PRINT   = 0.01;
    % Number of coins for each coins types present in the cash box
    NB_COINS_PER_TYPE = 5;
    NB_COINS_TYPES    = 4;
    NB_COINS_SLOTS    = 9;
    % Y position where the print of the ticket starts
    BASELINE_TICKET_POS = 405;
  end

  properties (Access = private)
    % Ticket's parameter
    TicketLength
    TicketPixelsInc = 10;
    TicketTagLabel = '';
    % Main timer of the App to check emulated registers content
    MainTimer
    % Old HW registers values (to check if any App components refresh is needed)
    OLD_DISPLAY_REGISTER_1       = uint8(0);
    OLD_DISPLAY_REGISTER_2       = uint8(0);
    OLD_DISPLAY_REGISTER_3       = uint8(0);
    OLD_PERIPHERALS_REGISTER     = uint8(0);
    OLD_BUTTONS_REGISTER         = uint8(0);
    OLD_INSERTED_COINS_REGISTER  = uint8(0);
    OLD_AVAILABLE_COINS_REGISTER = uint8(0);
    OLD_CASH_BOX_COINS           = uint8(0);
  end

  properties (Access = public)
    % Emulated HW registers
    DISPLAY_REGISTER_1       = uint8(0);
    DISPLAY_REGISTER_2       = uint8(0);
    DISPLAY_REGISTER_3       = uint8(0);
    PERIPHERALS_REGISTER     = uint8(0);
    BUTTONS_REGISTER         = uint8(0);
    INSERTED_COINS_REGISTER  = uint8(0);
    AVAILABLE_COINS_REGISTER = uint8(0);
    % Object to Group the three 7-segment displays
    DisplaysGroup
    % Physical cash box of the parking meter
    CASH_BOX_COINS           = uint8(0);
    % Indicates if the App is running or not
    Running = 'off';
  end
  
  methods (Access = private)
    function [] = TimerMain(app)
      % Refresh the first 7-segment display
      if (app.DISPLAY_REGISTER_1 ~= app.OLD_DISPLAY_REGISTER_1)
        setSevenSegmentDisplay(app.DisplaysGroup,app.DISPLAY_REGISTER_1,1);
        app.OLD_DISPLAY_REGISTER_1 = app.DISPLAY_REGISTER_1;
      end
      % Refresh the second 7-segment display
      if (app.DISPLAY_REGISTER_2 ~= app.OLD_DISPLAY_REGISTER_2)
        setSevenSegmentDisplay(app.DisplaysGroup,app.DISPLAY_REGISTER_2,2);
        app.OLD_DISPLAY_REGISTER_2 = app.DISPLAY_REGISTER_2;
      end
      % Refresh the third 7-segment display
      if (app.DISPLAY_REGISTER_3 ~= app.OLD_DISPLAY_REGISTER_3)
        setSevenSegmentDisplay(app.DisplaysGroup,app.DISPLAY_REGISTER_3,3);
        app.OLD_DISPLAY_REGISTER_3 = app.DISPLAY_REGISTER_3;
      end
      % Check if there are inserted coins
      if (app.INSERTED_COINS_REGISTER ~= app.OLD_INSERTED_COINS_REGISTER)
        % Reset status of the inserted coins, green and red pressed buttons
        app.INSERTED_COINS_REGISTER = bitand(app.INSERTED_COINS_REGISTER,bitcmp(0x1F));
      end
      % Refresh any peripheral components
      if (app.PERIPHERALS_REGISTER ~= app.OLD_PERIPHERALS_REGISTER)
        % Refresh the ticket printing status
        if (bitand(app.PERIPHERALS_REGISTER,app.BIT_MASK_PRINT_TICKET) &&...
            ~bitand(app.OLD_PERIPHERALS_REGISTER,app.BIT_MASK_PRINT_TICKET))
          % Get current date and time and add one hour (1/24) toit
          ticketTagText = datestr(datetime(now+1/24,'ConvertFrom','datenum'));
          % Create the date and time tag to add to the bottom of the ticket
          app.TicketTagLabel = uilabel(app.LabelTag,'Text',ticketTagText,...
                                       'HorizontalAlignment','center',...
                                       'FontSize',12,'FontWeight','bold');
          app.TicketTagLabel.Layout.Row = 2;
          app.TicketTagLabel.Layout.Column = 1;
          app.ImageTicket.Visible = 'on';
          % Print the ticket with a rolling effect
          for k=app.TicketPixelsInc:app.TicketPixelsInc:app.TicketLength
            pause(app.TIME_TICKET_PRINT);
            app.PanelTicketLabel.Position(2) = app.BASELINE_TICKET_POS-k;
            app.PanelTicketLabel.Position(4) = k;
            app.GridTicket.RowHeight(2) = {k};
          end
        end
        % Refresh the flap and coins status
        if bitand(app.PERIPHERALS_REGISTER,app.BIT_MASK_COINS_FLAP)
          app.ImageFlap.Visible = 'off';
          app.ImageCoin_010.Enable  = 'on';
          app.ImageCoin_020.Enable  = 'on';
          app.ImageCoin_050.Enable  = 'on';
          app.ImageCoin_100.Enable  = 'on';
          app.ImageCoin_200.Enable  = 'on';
        else
          app.ImageFlap.Visible = 'on';
          app.ImageCoin_010.Enable  = 'off';
          app.ImageCoin_020.Enable  = 'off';
          app.ImageCoin_050.Enable  = 'off';
          app.ImageCoin_100.Enable  = 'off';
          app.ImageCoin_200.Enable  = 'off';
        end
        % Check if there are coins to give back
        if bitand(app.PERIPHERALS_REGISTER,app.BIT_MASK_CHANGE_COINS)
          % Check if a coin's slot is available
          for k=1:1:app.NB_COINS_SLOTS
            evalc(['slotKfree = isempty(app.ImageCoinSlot_' num2str(k) '.ImageSource)']);
            if (slotKfree)
              idx = num2str(k);
              break;
            end
          end
          CoinsIdx = 0;
          % Refresh the 100 cents coin for change status
          if (bitand(app.PERIPHERALS_REGISTER,app.BIT_MASK_100_CENTS) && app.CASH_BOX_COINS(4) > 0)
            CoinsChange = 'Coins_100.png';
            CoinsIdx = 4;
          end
          % Refresh the 50 cents coin for change status
          if (bitand(app.PERIPHERALS_REGISTER,app.BIT_MASK_050_CENTS) && app.CASH_BOX_COINS(3) > 0)
            CoinsChange = 'Coins_50.png';
            CoinsIdx = 3;
          end
          % Refresh the 20 cents coin for change status
          if (bitand(app.PERIPHERALS_REGISTER,app.BIT_MASK_020_CENTS) && app.CASH_BOX_COINS(2) > 0)
            CoinsChange = 'Coins_20.png';
            CoinsIdx = 2;
          end
          % Refresh the 10 cents coin for change status
          if (bitand(app.PERIPHERALS_REGISTER,app.BIT_MASK_010_CENTS) && app.CASH_BOX_COINS(1) > 0)
            CoinsChange = 'Coins_10.png';
            CoinsIdx = 1;
          end
          if (CoinsIdx > 0)
            app.CASH_BOX_COINS(CoinsIdx) = app.CASH_BOX_COINS(CoinsIdx)-1;
            if (slotKfree)
              evalc(['app.ImageCoinSlot_' idx '.ImageSource = ''' CoinsChange '''']);
              evalc(['app.ImageCoinSlot_' idx '.Visible = ''on''']);
            end
          end
        end
        % Save current peripheral register status
        app.OLD_PERIPHERALS_REGISTER = app.PERIPHERALS_REGISTER;
        % Reset status of the green and red pressed buttons
        app.BUTTONS_REGISTER = bitand(app.BUTTONS_REGISTER,bitcmp(app.BIT_MASK_GREEN_BUTTON));
        app.BUTTONS_REGISTER = bitand(app.BUTTONS_REGISTER,bitcmp(app.BIT_MASK_RED_BUTTON));
      end
      % Refresh the cash box status
      if ((sum(app.CASH_BOX_COINS) ~= sum(app.OLD_CASH_BOX_COINS)) && any(app.CASH_BOX_COINS == 0))
        idxEmpty = find(app.CASH_BOX_COINS == 0);
        app.AVAILABLE_COINS_REGISTER = app.BIT_MASK_CHANGE_COINS-sum(uint8(2.^(idxEmpty-1)));
        if (app.AVAILABLE_COINS_REGISTER ~= app.OLD_AVAILABLE_COINS_REGISTER)
          app.PERIPHERALS_REGISTER = bitor(app.PERIPHERALS_REGISTER,app.BIT_MASK_ADD_LED);
          app.PERIPHERALS_REGISTER = bitand(app.PERIPHERALS_REGISTER,bitcmp(app.BIT_MASK_RETURN_LED));
          % Refresh Give change LED
          if bitand(app.PERIPHERALS_REGISTER,app.BIT_MASK_RETURN_LED)
            app.LedGiveChange.Color = [0 1 0];
          else
            app.LedGiveChange.Color = [0.5 0.5 0.5];
          end
          % Refresh Add coins LED
          if bitand(app.PERIPHERALS_REGISTER,app.BIT_MASK_ADD_LED)
            app.LedAddCoins.Color = [1 0 0];
          else
            app.LedAddCoins.Color = [0.5 0.5 0.5];
          end
          % Save current peripheral register status
          app.OLD_PERIPHERALS_REGISTER = app.PERIPHERALS_REGISTER;
        end
        % Save current change registers status
        app.OLD_CASH_BOX_COINS = app.CASH_BOX_COINS;
        app.OLD_AVAILABLE_COINS_REGISTER = app.AVAILABLE_COINS_REGISTER;
      end
    end
    
    function [] = CashBoxReset(app)
      % Reset the amount of coins available in the cashbox
      app.CASH_BOX_COINS = app.NB_COINS_PER_TYPE*ones(1,app.NB_COINS_TYPES,'uint8');
      app.OLD_CASH_BOX_COINS = app.CASH_BOX_COINS;
      app.AVAILABLE_COINS_REGISTER = app.BIT_MASK_CHANGE_COINS;
      app.OLD_AVAILABLE_COINS_REGISTER = app.AVAILABLE_COINS_REGISTER;
      % Leds status when the cash box is fully loaded
      app.LedGiveChange.Color = [0 1 0];
      app.LedAddCoins.Color = [0.5 0.5 0.5];
    end
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function StartupFcn(app)
      % Command to instanciate the object in the workspace to share data with the low-level MATLAB algorithmic part
      % app = ParkingMeterGUI;

      % Get the master switch status
      SwitchMasterValueChanged(app);
      % Initialization of the leds and the cash box content
      app.CashBoxReset();
      % Initialization of the ticket's length
      app.TicketLength = app.PanelTicketLabel.Position(4);
      app.PanelTicketLabel.Position(2) = app.BASELINE_TICKET_POS;
      app.PanelTicketLabel.Position(4) = 0;
      % Instanciate one 7-Segment displays component
      app.DisplaysGroup = SevenSegmentDisplays(app.NB_DISPLAYS,app.Grid7Segments,...
                                               app.SEGMENTS_COLOR,app.BACKGROUND_COLOR);
      % Change segments color to black for the normal App behavior and order the displays to the right
      app.DisplaysGroup.SegmentsColor = [0 0 0];
      app.DisplaysGroup.Endianness = 'Right';
      % Setup of the timer to poll status or change values of the emulated HW registers
      app.MainTimer = timer('Period',app.TIMER_PERIOD,'ExecutionMode','fixedRate');
      % Function that is periodically called from the timer
      app.MainTimer.TimerFcn = @(~,~)app.TimerMain();
      start(app.MainTimer);
      % Instanciation of the App done
      app.Running = 'on';
    end

    % Image clicked function: ImageCashBoxKey
    function ImageCashBoxKeyImageClicked(app, event)
      app.ImageCashBoxKey.ImageSource = 'Key90.png';
      waitfor(msgbox('The cask box has been reset properly.','Information','help'));
      app.ImageCashBoxKey.ImageSource = 'Key.png';
      % Reset the leds and the amount of coins available in the cashbox
      app.CashBoxReset();
    end

    % Value changed function: SwitchMaster
    function SwitchMasterValueChanged(app, event)
      if strcmp(app.SwitchMaster.Value,'Off')
        app.BUTTONS_REGISTER = bitand(app.BUTTONS_REGISTER,bitcmp(app.BIT_MASK_MASTER_SWITCH));
      else
        app.BUTTONS_REGISTER = bitor(app.BUTTONS_REGISTER,app.BIT_MASK_MASTER_SWITCH);
      end
    end

    % Button pushed function: ButtonGreen
    function ButtonGreenPushed(app, event)
      app.BUTTONS_REGISTER = bitor(app.BUTTONS_REGISTER,app.BIT_MASK_GREEN_BUTTON);
    end

    % Button pushed function: ButtonRed
    function ButtonRedPushed(app, event)
      app.BUTTONS_REGISTER = bitor(app.BUTTONS_REGISTER,app.BIT_MASK_RED_BUTTON);
    end

    % Image clicked function: ImageCoin_200
    function ImageCoin_200ImageClicked(app, event)
      app.ImageCoin_200.Enable = 'off'; % Button push effect
      pause(app.TIME_COINS_INSERTED);
      app.INSERTED_COINS_REGISTER = bitor(app.INSERTED_COINS_REGISTER,app.BIT_MASK_200_CENTS);
      app.ImageCoin_200.Enable = 'on'; % Button release effect
    end

    % Image clicked function: ImageCoin_100
    function ImageCoin_100ImageClicked(app, event)
      app.ImageCoin_100.Enable = 'off'; % Button push effect
      pause(app.TIME_COINS_INSERTED);
      app.INSERTED_COINS_REGISTER = bitor(app.INSERTED_COINS_REGISTER,app.BIT_MASK_100_CENTS);
      app.ImageCoin_100.Enable = 'on'; % Button release effect
    end

    % Image clicked function: ImageCoin_050
    function ImageCoin_050ImageClicked(app, event)
      app.ImageCoin_050.Enable = 'off'; % Button push effect
      pause(app.TIME_COINS_INSERTED);
      app.INSERTED_COINS_REGISTER = bitor(app.INSERTED_COINS_REGISTER,app.BIT_MASK_050_CENTS);
      app.ImageCoin_050.Enable = 'on'; % Button release effect
    end

    % Image clicked function: ImageCoin_020
    function ImageCoin_020ImageClicked(app, event)
      app.ImageCoin_020.Enable = 'off'; % Button push effect
      pause(app.TIME_COINS_INSERTED);
      app.INSERTED_COINS_REGISTER = bitor(app.INSERTED_COINS_REGISTER,app.BIT_MASK_020_CENTS);
      app.ImageCoin_020.Enable = 'on'; % Button release effect
    end

    % Image clicked function: ImageCoin_010
    function ImageCoin_010ImageClicked(app, event)
      app.ImageCoin_010.Enable = 'off'; % Button push effect
      pause(app.TIME_COINS_INSERTED);
      app.INSERTED_COINS_REGISTER = bitor(app.INSERTED_COINS_REGISTER,app.BIT_MASK_010_CENTS);
      app.ImageCoin_010.Enable = 'on'; % Button release effect
    end

    % Image clicked function: ImageTicket
    function ImageTicketClicked(app, event)
      % Hide the ticket and reset its length
      app.TicketTagLabel.Text = '';
      app.ImageTicket.Visible = 'off';
      app.PanelTicketLabel.Position(4) = 0;
      app.GridTicket.RowHeight(2) = {0};
    end

    % Image clicked function: ImageCoinSlot_1, ImageCoinSlot_2, 
    % ...and 7 other components
    function ImageCoinSlotXImageClicked(app, event)
      for k=1:1:app.NB_COINS_SLOTS
        evalc(['app.ImageCoinSlot_' num2str(k) '.Visible = ''off''']);
        evalc(['app.ImageCoinSlot_' num2str(k) '.ImageSource = ''''']);
      end
    end

    % Close request function: ParkingMeterUIFigure
    function ParkingMeterUIFigureCloseRequest(app, event)
      stop(timerfind);
      delete(app);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      % Get the file path for locating images
      pathToMLAPP = fileparts(mfilename('fullpath'));
      app.ParkingMeterUIFigure = uifigure('Visible', 'off');
      app.ParkingMeterUIFigure.Name = 'Parking Meter @ MathWorks';
      app.ParkingMeterUIFigure.CloseRequestFcn = createCallbackFcn(app, @ParkingMeterUIFigureCloseRequest, true);
      app.GridLayout = uigridlayout(app.ParkingMeterUIFigure);
      app.ImageBackground = uiimage(app.GridLayout);
      app.ImageBackground.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Parking_Meter.png');
      app.ImageInstructions = uiimage(app.GridLayout);
      app.ImageInstructions.ImageSource = 'Parking_Text.png';
      app.ImageCoinsChange = uiimage(app.GridLayout);
      app.ImageCoinsChange.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Background_Coins_Change.png');
      app.ImageCoinSlot_9 = uiimage(app.GridLayout);
      app.ImageCoinSlot_9.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_9.Visible = 'off';
      app.ImageCoinSlot_8 = uiimage(app.GridLayout);
      app.ImageCoinSlot_8.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_8.Visible = 'off';
      app.ImageCoinSlot_7 = uiimage(app.GridLayout);
      app.ImageCoinSlot_7.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_7.Visible = 'off';
      app.ImageCoinSlot_6 = uiimage(app.GridLayout);
      app.ImageCoinSlot_6.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_6.Visible = 'off';
      app.ImageCoinSlot_5 = uiimage(app.GridLayout);
      app.ImageCoinSlot_5.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_5.Visible = 'off';
      app.ImageCoinSlot_4 = uiimage(app.GridLayout);
      app.ImageCoinSlot_4.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_4.Visible = 'off';
      app.ImageCoinSlot_3 = uiimage(app.GridLayout);
      app.ImageCoinSlot_3.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_3.Visible = 'off';
      app.ImageCoinSlot_2 = uiimage(app.GridLayout);
      app.ImageCoinSlot_2.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_2.Visible = 'off';
      app.ImageCoinSlot_1 = uiimage(app.GridLayout);
      app.ImageCoinSlot_1.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_1.Visible = 'off';
      app.ImageCashBoxKey = uiimage(app.GridLayout);
      app.ImageCashBoxKey.ImageClickedFcn = createCallbackFcn(app, @ImageCashBoxKeyImageClicked, true);
      app.ImageCashBoxKey.ImageSource = 'Key.png';
      app.ImageCoin_200 = uiimage(app.GridLayout);
      app.ImageCoin_200.ImageClickedFcn = createCallbackFcn(app, @ImageCoin_200ImageClicked, true);
      app.ImageCoin_200.Enable = 'off';
      app.ImageCoin_200.ImageSource = 'Coins_200.png';
      app.ImageCoin_100 = uiimage(app.GridLayout);
      app.ImageCoin_100.ImageClickedFcn = createCallbackFcn(app, @ImageCoin_100ImageClicked, true);
      app.ImageCoin_100.Enable = 'off';
      app.ImageCoin_100.ImageSource = 'Coins_100.png';
      app.ImageCoin_050 = uiimage(app.GridLayout);
      app.ImageCoin_050.ImageClickedFcn = createCallbackFcn(app, @ImageCoin_050ImageClicked, true);
      app.ImageCoin_050.Enable = 'off';
      app.ImageCoin_050.ImageSource = 'Coins_50.png';
      app.ImageCoin_020 = uiimage(app.GridLayout);
      app.ImageCoin_020.ImageClickedFcn = createCallbackFcn(app, @ImageCoin_020ImageClicked, true);
      app.ImageCoin_020.Enable = 'off';
      app.ImageCoin_020.ImageSource = 'Coins_20.png';
      app.ImageCoin_010 = uiimage(app.GridLayout);
      app.ImageCoin_010.ImageClickedFcn = createCallbackFcn(app, @ImageCoin_010ImageClicked, true);
      app.ImageCoin_010.Enable = 'off';
      app.ImageCoin_010.ImageSource = 'Coins_10.png';
      app.ButtonGreen = uibutton(app.GridLayout, 'push');
      app.ButtonGreen.ButtonPushedFcn = createCallbackFcn(app, @ButtonGreenPushed, true);
      app.ButtonGreen.Text = '';
      app.ButtonRed = uibutton(app.GridLayout, 'push');
      app.ButtonRed.ButtonPushedFcn = createCallbackFcn(app, @ButtonRedPushed, true);
      app.ButtonRed.Text = '';
      app.ImageFlap = uiimage(app.GridLayout);
      app.ImageFlap.ImageSource = 'Flap.png';
      app.CoinsforchangeLabel = uilabel(app.GridLayout);
      app.CoinsforchangeLabel.Text = 'Coins for change';
      app.AddcoinsLampLabel = uilabel(app.GridLayout);
      app.AddcoinsLampLabel.Text = 'Add coins';
      app.LedAddCoins = uilamp(app.GridLayout);
      app.GivechangeLampLabel = uilabel(app.GridLayout);
      app.GivechangeLampLabel.Text = 'Give change';
      app.LedGiveChange = uilamp(app.GridLayout);
      app.SwitchMaster = uiswitch(app.GridLayout, 'slider');
      app.SwitchMaster.ValueChangedFcn = createCallbackFcn(app, @SwitchMasterValueChanged, true);
      app.SwitchMaster.Value = 'On';
      app.MasterSwitchLabel = uilabel(app.GridLayout);
      app.MasterSwitchLabel.Text = 'Master Switch';
      app.Panel7Segments = uipanel(app.GridLayout);
      app.Grid7Segments = uigridlayout(app.Panel7Segments);
      app.PanelTicketLabel = uipanel(app.ParkingMeterUIFigure);
      app.GridTicket = uigridlayout(app.PanelTicketLabel);
      app.LabelTag = uigridlayout(app.GridTicket);
      app.ImageTicket = uiimage(app.GridTicket);
      app.ImageTicket.ImageClickedFcn = createCallbackFcn(app, @ImageTicketClicked, true);
      app.ImageTicket.Visible = 'off';
      app.ImageTicket.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Ticket.png');
      % Show the figure after all components are created
      app.ParkingMeterUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = ParkingMeterGUI

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.ParkingMeterUIFigure)

      % Execute the startup function
      runStartupFcn(app, @StartupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.ParkingMeterUIFigure)
    end
  end
end